classdef Image_Positions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        HowManyTumorsEditField         matlab.ui.control.NumericEditField
        HowManyTumorsEditFieldLabel    matlab.ui.control.Label
        HowManyGlandsEditField         matlab.ui.control.NumericEditField
        HowManyGlandsEditFieldLabel    matlab.ui.control.Label
        CalculatePositionsButton       matlab.ui.control.Button
        RadiusofPhantomcmEditField     matlab.ui.control.NumericEditField
        RadiusofPhantomcmEditFieldLabel  matlab.ui.control.Label
        HowManyAntennasEditField       matlab.ui.control.NumericEditField
        HowManyAntennasEditFieldLabel  matlab.ui.control.Label
        AddAntennaPositionsButton      matlab.ui.control.Button
        AddGlandPositionButton         matlab.ui.control.Button
        AddTumorPositionButton         matlab.ui.control.Button
        AddPhantomPositionButton       matlab.ui.control.Button
        EnterImageFileEditField        matlab.ui.control.EditField
        EnterImageFileEditFieldLabel   matlab.ui.control.Label
        PhantomPotisionsLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        phantomPosition
        tumorPosition
        glandPosition
        antennaPositions
        phantomCenter
        phantomRadius
        tumorCenters
        tumorRadii
        glandCenters
        glandRadii
        imageHandle 
    end
    
    methods (Access = private)
        function updatePhantom(app, src)
            index = src.UserData;
            app.phantomCenter(index, :) = src.Center;
            app.phantomRadius(index) = src.Radius;
        end
        function updateROI(app, src, typeName)
            index = src.UserData;
            app.([typeName, 'Centers'])(index, :) = src.Center;
            app.([typeName, 'Radii'])(index) = src.Radius;
        end

        function updateAntenna(app, src)
            index = src.UserData;
            app.antennaPositions(index, :) = src.Position;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterImageFileEditField
        function EnterImageFileEditFieldValueChanged(app, event)
        enteredPath = app.EnterImageFileEditField.Value;
    
        if isfile(enteredPath)
            newImg = imread(enteredPath);
            
            
            if isempty(app.imageHandle) || ~isvalid(app.imageHandle)
                app.imageHandle = imshow(newImg, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'tight');
                axis(app.UIAxes, 'image');
            else
                app.imageHandle.CData = newImg;
                    app.UIAxes.XLim = [0.5, size(newImg, 2) + 0.5];
                    app.UIAxes.YLim = [0.5, size(newImg, 1) + 0.5];
            end
        else
            cla(app.UIAxes);
            app.imageHandle = []; 
            uialert(app.UIFigure, 'The file path provided does not exist.', 'Invalid File');
        end

        end

        % Button pushed function: AddPhantomPositionButton
        function AddPhantomPositionButtonPushed(app, event)
            oldPhantom = findobj(app.UIAxes, 'Type', 'images.roi.Circle', 'Tag', 'Phantom');
            if ~isempty(oldPhantom)
                delete(oldPhantom);
            end            
            
            roiPhantom = drawcircle(app.UIAxes,'color','red', 'FaceAlpha', 0, 'Tag', 'Phantom');
            roiPhantom.UserData = 1;
            app.phantomCenter = roiPhantom.Center;
            app.phantomRadius = roiPhantom.Radius;
            addlistener(roiPhantom, 'ROIMoved', @(src, evt) app.updatePhantom(src));

        end

        % Button pushed function: AddTumorPositionButton
        function AddTumorPositionButtonPushed(app, event)
            oldTumors = findobj(app.UIAxes, 'Type', 'images.roi.Circle', 'Tag', 'Tumor');
            if ~isempty(oldTumors)
                delete(oldTumors);
            end            
            
            numberOfTumors = app.HowManyTumorsEditField.Value;
            app.tumorCenters = zeros(numberOfTumors, 2);
            app.tumorRadii = zeros(numberOfTumors, 1);

            for i = 1:numberOfTumors
                roiTumor = drawcircle(app.UIAxes,'color','yellow', 'FaceAlpha', 0, 'Tag', 'Tumor');
                roiTumor.UserData = i;
                app.tumorCenters(i,:) = roiTumor.Center;
                app.tumorRadii(i) = roiTumor.Radius;
                addlistener(roiTumor, 'ROIMoved', @(src, evt) app.updateROI(src,'tumor'));
            end
        end

        % Button pushed function: AddGlandPositionButton
        function AddGlandPositionButtonPushed(app, event)
            oldGlands = findobj(app.UIAxes, 'Type', 'images.roi.Circle', 'Tag', 'Gland');
            if ~isempty(oldGlands)
                delete(oldGlands);
            end

            numberOfGlands = app.HowManyGlandsEditField.Value;
            app.glandCenters = zeros(numberOfGlands, 2);
            app.glandRadii = zeros(numberOfGlands, 1);

            for i = 1:numberOfGlands
                roiGland = drawcircle(app.UIAxes,'color','green', 'FaceAlpha', 0, 'Tag', 'Gland');
                roiGland.UserData = i;
                app.glandCenters(i,:) = roiGland.Center;
                app.glandRadii(i) = roiGland.Radius;
                addlistener(roiGland, 'ROIMoved', @(src, evt) app.updateROI(src,'gland'));
            end
        end

        % Button pushed function: AddAntennaPositionsButton
        function AddAntennaPositionsButtonPushed(app, event)
            oldAntennas = findobj(app.UIAxes, 'Type', 'images.roi.Point', 'Tag', 'Antenna');
            if ~isempty(oldAntennas)
                delete(oldAntennas);
            end
            
            numberOfAntennas = app.HowManyAntennasEditField.Value; 
            app.antennaPositions = zeros(numberOfAntennas, 2);

            for i = 1:numberOfAntennas
                roiAntenna = drawpoint(app.UIAxes, 'Tag', 'Antenna');
                roiAntenna.UserData = i;
                app.antennaPositions(i,:) = roiAntenna.Position; 
                addlistener(roiAntenna, 'ROIMoved', @(src, evt) app.updateAntenna(src));
            end
        end

        % Button pushed function: CalculatePositionsButton
        function CalculatePositionsButtonPushed(app, event)
            physicalRadius = app.RadiusofPhantomcmEditField.Value;
            radiusMeters = physicalRadius/100;
            scale = radiusMeters/app.phantomRadius;
        
            tumorRadiiMeters = app.tumorRadii*scale;
            glandRadiiMeters = app.glandRadii*scale;
            phantomCenterMeters = app.phantomCenter*scale;
            phantomCenterMeters(:,2) = -phantomCenterMeters(:,2);
            tumorCentersMeters = app.tumorCenters*scale;
            tumorCentersMeters(:,2) = -tumorCentersMeters(:,2);
            glandCentersMeters = app.glandCenters*scale;
            glandCentersMeters(:,2) = -glandCentersMeters(:,2);
            antennaPositionsMeters = app.antennaPositions*scale;
            antennaPositionsMeters(:,2) = -antennaPositionsMeters(:,2);
            tumorCentersMeters = tumorCentersMeters - phantomCenterMeters;
            glandCentersMeters = glandCentersMeters - phantomCenterMeters;
            antennaPositionsMeters = antennaPositionsMeters - phantomCenterMeters;
            phantomCenterMeters = phantomCenterMeters - phantomCenterMeters;


            positionFromPhoto(phantomCenterMeters, radiusMeters, ...
                tumorCentersMeters, tumorRadiiMeters, ...
                glandCentersMeters, glandRadiiMeters, ...
                antennaPositionsMeters)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PhantomPotisionsLabel = uilabel(app.LeftPanel);
            app.PhantomPotisionsLabel.Text = 'Phantom Potisions';
            app.EnterImageFileEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterImageFileEditFieldLabel.Text = 'Enter Image File';
            app.EnterImageFileEditField = uieditfield(app.LeftPanel, 'text');
            app.EnterImageFileEditField.ValueChangedFcn = createCallbackFcn(app, @EnterImageFileEditFieldValueChanged, true);
            app.EnterImageFileEditField.Placeholder = 'Ex: img.jpg';
            app.AddPhantomPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddPhantomPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhantomPositionButtonPushed, true);
            app.AddPhantomPositionButton.Text = 'Add Phantom Position';
            app.AddTumorPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddTumorPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddTumorPositionButtonPushed, true);
            app.AddTumorPositionButton.Text = 'Add Tumor Position';
            app.AddGlandPositionButton = uibutton(app.LeftPanel, 'push');
            app.AddGlandPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddGlandPositionButtonPushed, true);
            app.AddGlandPositionButton.Text = 'Add Gland Position';
            app.AddAntennaPositionsButton = uibutton(app.LeftPanel, 'push');
            app.AddAntennaPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddAntennaPositionsButtonPushed, true);
            app.AddAntennaPositionsButton.Text = 'Add Antenna Positions';
            app.HowManyAntennasEditFieldLabel = uilabel(app.LeftPanel);
            app.HowManyAntennasEditFieldLabel.Text = 'How Many Antennas';
            app.HowManyAntennasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HowManyAntennasEditField.Value = 8;
            app.RadiusofPhantomcmEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusofPhantomcmEditFieldLabel.Text = 'Radius of Phantom (cm)';
            app.RadiusofPhantomcmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusofPhantomcmEditField.Value = 7.3;
            app.CalculatePositionsButton = uibutton(app.LeftPanel, 'push');
            app.CalculatePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePositionsButtonPushed, true);
            app.CalculatePositionsButton.Text = 'Calculate Positions';
            app.HowManyGlandsEditFieldLabel = uilabel(app.LeftPanel);
            app.HowManyGlandsEditFieldLabel.Text = 'How Many Glands';
            app.HowManyGlandsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HowManyGlandsEditField.Value = 1;
            app.HowManyTumorsEditFieldLabel = uilabel(app.LeftPanel);
            app.HowManyTumorsEditFieldLabel.Text = 'How Many Tumors';
            app.HowManyTumorsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HowManyTumorsEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Positions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end